classdef Robokit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GripperEditField            matlab.ui.control.NumericEditField
        GripperEditFieldLabel       matlab.ui.control.Label
        JointVariablesLabel         matlab.ui.control.Label
        PositionVectorLabel         matlab.ui.control.Label
        ARDUINOConnectionLamp       matlab.ui.control.Lamp
        ARDUINOConnectionLampLabel  matlab.ui.control.Label
        zEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel             matlab.ui.control.Label
        T3EditField                 matlab.ui.control.NumericEditField
        T3EditFieldLabel            matlab.ui.control.Label
        T2EditField                 matlab.ui.control.NumericEditField
        T2EditFieldLabel            matlab.ui.control.Label
        T1EditField                 matlab.ui.control.NumericEditField
        T1EditFieldLabel            matlab.ui.control.Label
        InverseButton               matlab.ui.control.Button
        ForwardButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
        properties (Access = public)
        RobotModel      % The SerialLink model
        SerialObj  % The Arduino connection
        q_current = [0 0 0] % Store current state % Description
        end
    
    methods (Access = private)
        
        function animateAndSend(app, q_new)
        q_traj = jtraj(app.q_current, q_new, 10);
    
    axes(app.UIAxes);
    for i = 1:size(q_traj, 1)
        app.RobotModel.plot(q_traj(i,:));
        drawnow(); 
    end
    
    app.q_current = q_new;
    
    if ~isempty(app.SerialObj)
        % Map math to UI degrees
        s1 = round(rad2deg(q_new(1)));
        s2 = round(rad2deg(-q_new(2))); % The inversion for your physical setup
        s3 = round(rad2deg(q_new(3)));
        % GET THE GRIPPER VALUE FROM THE UI

        gripVal = round(app.GripperEditField.Value);
        
        % Use the 4-parameter string to match the Arduino sscanf
        cmd = sprintf("SERVO %d %d %d %d\n", s1, s2, s3, gripVal);
        writeline(app.SerialObj, cmd);
    end
        end
    end
          

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Define DH Parameters
    % [theta, d, a, alpha]
    L1 = Link([0, 115, 0, pi/2]);
    L2 = Link([0, 0, 115, 0]);
    L2.offset = pi/2;
    L3 = Link([0, 0, 130, 0]);
    L3.offset = 3*pi/2;
    app.RobotModel = SerialLink([L1 L2 L3], 'name', '3-DOF');
    
    % 2. Force plot into UIAxes
    % We use 'noname' and 'base' to prevent it from popping a new window
    axes(app.UIAxes); 
    app.RobotModel.plot(app.q_current, 'workspace', [-250 250 -250 250 -50 250], 'noname');
    
    % 3. Serial Connection
    try
        app.SerialObj = serialport("COM8", 115200);
        configureTerminator(app.SerialObj, "LF");
        pause(2); % CRITICAL: Give Arduino 2 seconds to reboot after connecting
        
        app.ARDUINOConnectionLamp.Color = 'green';
        
        % SYNC ON STARTUP: Send the current [0 0 0] to Arduino
        app.animateAndSend(app.q_current);
        
    catch
        app.SerialObj = [];
        app.ARDUINOConnectionLamp.Color = 'red';
    end

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % 1. Get values from UI
    q = [deg2rad(app.T1EditField.Value), ...
         deg2rad(-app.T2EditField.Value), ...
         deg2rad(app.T3EditField.Value)];

    % 2. Forward Kinematics
    T = app.RobotModel.fkine(q);
    
    % 3. Update UI Position Fields
    app.xEditField.Value = round(T.t(1),2);
    app.yEditField.Value = round(T.t(2),2);
    app.zEditField.Value = round(T.t(3),2);
    
    % 4. Animate and Move Hardware
    app.animateAndSend(q);
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % 1. Get the desired position from UI
    target_pos = [app.xEditField.Value, app.yEditField.Value, app.zEditField.Value];
    
    % 2. Error function: Distance between current tip and target
    error_func = @(q) norm(app.RobotModel.fkine(q).t' - target_pos);
    
    % 3. Optimization: Start from a "neutral" guess to avoid weird-degree solutions
    % We use [current_t1, -pi/4, -pi/4] as a hint to stay in a "natural" pose
    q_guess = [app.q_current(1), -pi/4, -pi/4]; 
    options = optimset('Display', 'off', 'TolX', 1e-4);
    [q_sol, fval] = fminsearch(error_func, q_guess, options);

    % 4. Check for reachability
    if fval > 1.0 
        uialert(app.UIFigure, 'Target out of reach!', 'IK Error');
        return;
    end
    
    % 5. MAP BACK TO UI (The Fix)
    % Python logic: T2_UI = -T2_Math | T3_UI = T3_Math
    t1_ui = rad2deg(q_sol(1));
    t2_ui = rad2deg(-q_sol(2)); % Negate back to match your Python input style
    t3_ui = rad2deg(q_sol(3));
    
    % Update the Edit Fields
    app.T1EditField.Value = round(t1_ui, 2);
    app.T2EditField.Value = round(t2_ui, 2);
    app.T3EditField.Value = round(t3_ui, 2);    
    
    % 6. Animate
    app.animateAndSend(q_sol);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: GripperEditField
        function GripperEditFieldValueChanged(app, event)
            if ~isempty(app.SerialObj)
        % We use app.q_current so the arm stays where it is
        app.animateAndSend(app.q_current);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ROBO')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T2EditFieldLabel = uilabel(app.UIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T3EditFieldLabel = uilabel(app.UIFigure);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.ARDUINOConnectionLampLabel = uilabel(app.UIFigure);
            app.ARDUINOConnectionLampLabel.Text = 'ARDUINO Connection';
            app.ARDUINOConnectionLamp = uilamp(app.UIFigure);
            app.PositionVectorLabel = uilabel(app.UIFigure);
            app.PositionVectorLabel.Text = {'Position'; 'Vector'};
            app.JointVariablesLabel = uilabel(app.UIFigure);
            app.JointVariablesLabel.Text = {'Joint '; 'Variables'};
            app.GripperEditFieldLabel = uilabel(app.UIFigure);
            app.GripperEditFieldLabel.Text = 'Gripper';
            app.GripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.GripperEditField.ValueChangedFcn = createCallbackFcn(app, @GripperEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robokit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end